classdef App02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StatusEditField              matlab.ui.control.EditField
        StatusEditFieldLabel         matlab.ui.control.Label
        DeleteButton                 matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        CurrentNumberEditField       matlab.ui.control.NumericEditField
        CurrentNumberEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        numbersCalled; % vector of numbers called
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            currentNumber = app.CurrentNumberEditField.Value;

            oldNumbers = app.numbersCalled;
            app.numbersCalled = ConcatenateCurrentNumberAndOldNumbers(currentNumber,oldNumbers);

            
            app.StatusEditField.Value = "Pressed 'Add' button";
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            currentNumber = app.CurrentNumberEditField.Value;
            
            app.StatusEditField.Value = "Pressed 'Delete' button";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentNumberEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentNumberEditFieldLabel.Text = 'Current Number';
            app.CurrentNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end